classdef Display < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure   matlab.ui.Figure
        ImageAxes  matlab.ui.control.UIAxes
    end

    
      properties (Access = private)
          MemoryTimer        % Timer object 
          CallingApp   % Main app object
          currentTime
          totalTime
          intervalImage
          timeseries
          passData
      end
      
      methods (Access = private)
    
       function MemoryTimerFcn(app,~,~)
            app.currentTime = app.currentTime + 1;
            if(app.currentTime > app.totalTime)
                stop(app.MemoryTimer);
                app.CallingApp.GenerateButton.Enable = 'on';
                delete(app.CallingApp);
                delete(app); 
            end
            
            index = app.timeseries(app.currentTime);
            
            if(index > 0)
              imagesc(app.ImageAxes, app.passData(index).picture);
              %imshow(app.passData(index).picture);
            elseif(index < 0)
              imagesc(app.ImageAxes, app.intervalImage);
              %imshow(app.intervalImage);
            end
            
       end 
       end
        
    
    methods (Access = public)
        
        function startTimer(app)
            start(app.MemoryTimer);
        end
        
        function stopTimer(app)
            %stop(app.MemoryTimer);
        end
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, passData)
            
            app.ImageAxes.Visible = 'off';
            app.ImageAxes.Colormap = gray(256);
            axis(app.ImageAxes, 'image');
            
            app.CallingApp = mainApp;
            app.timeseries = passData.timeseries;
            app.passData = passData;
            app.currentTime = 0;
            app.totalTime = passData.totalTime;
            app.intervalImage = passData.intervalImage;

            %disp(passData.picture);
            %imshow(app.intervalImage);
            %imagesc(app.ImageAxes, app.intervalImage);
            app.MemoryTimer = timer(...
                'ExecutionMode', 'fixedRate', ...    % Run timer repeatedly
                'Period', 0.25, ...                     % Period is 1 second
                'BusyMode', 'queue',...              % Queue timer callbacks when busy
                'TimerFcn', {@app.MemoryTimerFcn});    % Specify callback function  
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.CallingApp.GenerateButton.Enable = 'on';
            delete(app.CallingApp);
            delete(app);        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ImageAxes = uiaxes(app.UIFigure);
            app.ImageAxes.XTick = [];
            app.ImageAxes.XTickLabel = {'[ ]'};
            app.ImageAxes.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Display(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end