classdef Script < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        Menu            matlab.ui.container.Menu
        Panel           matlab.ui.container.Panel
        Button          matlab.ui.control.Button
        Label           matlab.ui.control.Label
        EditField       matlab.ui.control.EditField
        Panel_2         matlab.ui.container.Panel
        ButtonGroup     matlab.ui.container.ButtonGroup
        Button_2        matlab.ui.control.RadioButton
        Button_3        matlab.ui.control.RadioButton
        Label_2         matlab.ui.control.Label
        EditField_2     matlab.ui.control.NumericEditField
        ButtonGroup_2   matlab.ui.container.ButtonGroup
        Button_4        matlab.ui.control.RadioButton
        Button_5        matlab.ui.control.RadioButton
        Label_3         matlab.ui.control.Label
        EditField_3     matlab.ui.control.EditField
        Switch          matlab.ui.control.ToggleSwitch
        GenerateButton  matlab.ui.control.Button
        Panel_3         matlab.ui.container.Panel
        Label_4         matlab.ui.control.Label
        EditField_4     matlab.ui.control.EditField
        Button_6        matlab.ui.control.Button
        Button_7        matlab.ui.control.Button
    end

    
 properties (Access = private)
        DisplayApp
        series
        filepath
 end
 
    
    methods (Access = private)
        
        function results = generate(app)
            
        end
    end
 

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: Button, EditField
        function ButtonPushed(app, event)
            path = uigetdir;
            app.filepath = path;
            
            if (ischar(path))
                app.EditField.Value = path;
                files = dir(fullfile(path, '*.*'));
                picture = cell(1,length(files));
                for i = 1:length(files)-2
                    filename = fullfile(path, files(i+2).name);
                    pic = imread(filename);
                    picture{i} = pic;
                end
               app.Button.UserData = picture;
               app.EditField.UserData = files;
            end
        end

        % Callback function
        function TextAreaValueChanged(app, event)
   
        end

        % Callback function
        function ButtonGroup_2SelectionChanged(app, event)
            
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            selpath = uigetdir;
            app.EditField_4.Value = selpath;
            app.Button_6.UserData = selpath;
        end

        % Callback function
        function ButtonGroupSelectionChanged(app, event)
           
        end

        % Value changed function: EditField_3
        function EditField_3ValueChanged(app, event)
            value = app.EditField_3.Value;
            switch app.ButtonGroup_2.SelectedObject.Text %判断选中按钮
                case '随机值'
                    num = []
                    for i = 1 : length(value)
                        if(strcmp(value(i),',') == 0)
                            num = [num, str2num(value(i))];
                        end
                    end
                 app.EditField_3.UserData = num;
            end
            
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            if(strcmp(app.Switch.Value ,'On'))
               startTimer(app.DisplayApp);
            else
               %stopTimer(app.DisplayApp);
            end
        end

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            number = length(app.EditField.UserData) - 2;
            index = randperm(number,number);
            
            showTime = app.EditField_2.Value;
            
            if(strcmp(app.ButtonGroup.SelectedObject.Text,'持续闪烁'))
                intervalNum = zeros(1,number,'int32');
            else 
                if(strcmp(app.ButtonGroup_2.SelectedObject.Text,'随机值'))
                    intervalSize = length(app.EditField_3.UserData);
                    index2 = randi([1 intervalSize],1,number);
                    intervalNum = zeros(1,number,'int32');
                    for i = 1 : number
                        intervalNum(i) = app.EditField_3.UserData(index2(i));
                    end
                    %disp(intervalNum);
                else
                    intervalNum = zeros(1,number,'int32');
                    for i = 1 : number
                        intervalNum(i) = str2num(app.EditField_3.Value);              
                    end
                     %disp(intervalNum);
                end
            end
            
            totalTime = number * showTime(1);
            
            for i = 1 : number
                totalTime = totalTime + intervalNum(i);
            end
            
            intervalImage = imread('C:\Users\93933\Documents\MATLAB\Examples\R2019a\matlab\MortgageCalculatorExample\intervalmage\interval.png');
       
            timeseries = zeros(1,totalTime * 4,'int8');
            output = zeros(1,totalTime,'int8');
            currTime = 1;
            currTime2 = 1;
            for i = 1 : number
                for j = 1 : showTime
                    timeseries(currTime2) = index(i);
                    timeseries(currTime2 + 1) = -1;
                    timeseries(currTime2 + 2) = index(i);
                    timeseries(currTime2 + 3) = -1;
                    
                    currTime2 = currTime2 + 4;
                end
            
                timeseries(currTime2) = -1;
                timeseries(currTime2 + 1  : currTime2 + 4 * intervalNum(i) - 1) = 0;
                
                output(currTime : currTime + showTime - 1) = index(i);
                output(currTime + showTime : currTime + showTime + intervalNum(i) - 1) = -1;
                
                currTime = currTime + showTime + intervalNum(i);
                currTime2 = currTime2 + 4 * intervalNum(i);
            end


            str = datestr(datetime('now'));
            filen = [str, '.txt'];
            filen = strrep(filen,'-',' ');
            filen = strrep(filen,':',' ');
            path = ['C:\Users\93933\Desktop\monkey\', filen];
            fid = fopen(path, 'w');
            fprintf(fid, '%d ', output);
            fprintf(fid, '\r\n');
            fprintf(fid, 'showTime: %d \r\n', showTime);
            fprintf(fid, 'Interval Time: \r\n');
            for i = 1 : number
                fprintf(fid, '%d \r\n', intervalNum(i));
            end
            fprintf(fid, '\r\n');
            for i = 1 : length(app.EditField.UserData)-2
                fprintf(fid, ' %d: %s \r\n', i, app.EditField.UserData(i+2).name);
            end    
            fclose(fid);
            
            filen2 = [str,'.mat'];
            filen2 = strrep(filen2,'-',' ');
            filen2 = strrep(filen2,':',' ');
            strc = app.EditField.UserData;
            save(filen2,'output','showTime','intervalNum','strc');
            
            app.series = output;    
            app.GenerateButton.UserData = struct('timeseries',timeseries,'picture',app.Button.UserData,'intervalImage',intervalImage,'totalTime', totalTime*4 );
            
            app.GenerateButton.Enable = 'off';
            app.DisplayApp = Display(app, app.GenerateButton.UserData );
            %disp(output);
            
            disp(timeseries);
                         
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.DisplayApp);
            delete(app);  
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            path = fullfile(app.Button_6.UserData, 'timeSeries.txt');
            fid = fopen(path, 'w');
            fprintf(fid, '%d ', app.series);
            fclose(fid);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '图片选择';
            app.Button = uibutton(app.Panel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '文件夹选择';
            app.Label = uilabel(app.Panel);
            app.Label.Text = '文件夹路径';
            app.EditField = uieditfield(app.Panel, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Panel_2 = uipanel(app.UIFigure);
            app.Panel_2.Title = '闪烁设置';
            app.ButtonGroup = uibuttongroup(app.Panel_2);
            app.ButtonGroup.Title = '闪烁模式';
            app.Button_2 = uiradiobutton(app.ButtonGroup);
            app.Button_2.Text = '间隔闪烁';
            app.Button_2.Value = true;
            app.Button_3 = uiradiobutton(app.ButtonGroup);
            app.Button_3.Text = '持续闪烁';
            app.Label_2 = uilabel(app.Panel_2);
            app.Label_2.Text = '闪烁时间';
            app.EditField_2 = uieditfield(app.Panel_2, 'numeric');
            app.ButtonGroup_2 = uibuttongroup(app.Panel_2);
            app.ButtonGroup_2.Title = '间隔闪烁时间设置';
            app.Button_4 = uiradiobutton(app.ButtonGroup_2);
            app.Button_4.Text = '固定值';
            app.Button_4.Value = true;
            app.Button_5 = uiradiobutton(app.ButtonGroup_2);
            app.Button_5.Text = '随机值';
            app.Label_3 = uilabel(app.Panel_2);
            app.Label_3.Text = '间隔时间';
            app.EditField_3 = uieditfield(app.Panel_2, 'text');
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3ValueChanged, true);
            app.Switch = uiswitch(app.Panel_2, 'toggle');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.GenerateButton = uibutton(app.Panel_2, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.Panel_3 = uipanel(app.UIFigure);
            app.Panel_3.Title = '帧时间序列输出';
            app.Label_4 = uilabel(app.Panel_3);
            app.Label_4.Text = '输出路径';
            app.EditField_4 = uieditfield(app.Panel_3, 'text');
            app.Button_6 = uibutton(app.Panel_3, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '文件夹选择';
            app.Button_7 = uibutton(app.Panel_3, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '输出';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Script

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end